classdef rough1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        STARTButton                    matlab.ui.control.Button
        STOPButton                     matlab.ui.control.Button
        Lamp1                          matlab.ui.control.Lamp
        Starter_text                   matlab.ui.control.TextArea
        observerultrasonicbuzzerPanel  matlab.ui.container.Panel
        TextArea                       matlab.ui.control.TextArea
        Lamp2                          matlab.ui.control.Lamp
        UIAxes                         matlab.ui.control.UIAxes
        buzzeralarmLampLabel           matlab.ui.control.Label
        buzzeralarmLamp                matlab.ui.control.Lamp
        gate_opening_panelservomotorPanel  matlab.ui.container.Panel
        opengateSwitchLabel            matlab.ui.control.Label
        opengateSwitch                 matlab.ui.control.ToggleSwitch
        Lamp3                          matlab.ui.control.Lamp
        gatecapacityKnobLabel          matlab.ui.control.Label
        gatecapacityKnob               matlab.ui.control.Knob
        gateOutputGaugeLabel           matlab.ui.control.Label
        gateOutputGauge                matlab.ui.control.SemicircularGauge
        availabilityandsecuritypannelvibrationsensorPanel  matlab.ui.container.Panel
        carCount                       matlab.ui.control.EditField
        TotalcarswaitinginlaneLabel    matlab.ui.control.Label
        SMARTPARKINGSYSTEMLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        a; % arduino 
        start;
        stop;
       
        % starts the app
        u; % ultrasonic sensor object
        s; % servo motor object
        % stops all 
    end
    
    methods (Access = private)
        
        function gate_output= gate(app,servObj,gate_open_value)
             gate_open_value=gate_open_value/100;
             writePosition(servObj,gate_open_value);
             gate_output=readPosition(servObj);
             gate_output=gate_output*100;
             
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                clear app.a 
                app.a=arduino("COM4","Uno");
                app.s=servo(app.a,'D4');
               % app.u = ultrasonic(app.a,'D2','D3','OutputFormat','double')
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            % when system starts then start variable =1 and stop =0
            app.start=1; 
            app.stop=false;
            
            if (app.start==1)
                app.Starter_text.Value="Welcome to Smart parking System";
                app.Lamp1.Color='g';   % lamp b/w start and stop is green 
                
            elseif (app.stop==1)
                app.Starter_text.Value= "Sorry Smart Gate  is Currently Closed";
                app.Lamp1.Color='r';   % lamp b/w start and stop is red 
            end
            
 %  main function : while app is running 
           count=1;
           y=0;
           while(~app.stop)        
           % observer pannel :
%                distance =readDistance(app.u);
%                app.Lamp2.Color='g';
%                app.TextArea.Value="Car detected at distance  " + distance + "CM" ;
%                app.a.writeDigitalPin('D4',~distance);
               
               pushButton=app.a.readDigitalPin('D2');
               if(pushButton)
                 app.Lamp2.Color='g';
                 app.TextArea.Value="there is a Car at gate" ;  
                 app.a.writeDigitalPin('D3',0);
                 app.buzzeralarmLamp.Color= [.00,1.00,1.00]; % buzzer on lamp sky blue
                 pause(1);
                 app.a.writeDigitalPin('D3',1);
                 count=count+1;
                 
               else
                 app.Lamp2.Color='r';
                 app.TextArea.Value="";
                 app.a.writeDigitalPin('D3',1) 
                 app.buzzeralarmLamp.Color= [0.93,0.69,0.13];% buzzer off lamp orange
               end
              
              y=[y ,pushButton]; 
              plot(app.UIAxes ,y.^2);
              drawnow;
              pause(0.2);
              app.carCount.Value=int2str(count); 
           % gate pannel :
               
               sw=app.opengateSwitch.Value;
               
               if (sw=="On")
                  go=gate(app, app.s,app.gatecapacityKnob.Value); % for servo( s, opening_percent)
                  app.gateOutputGauge.Value=go;
                  app.Lamp3.Color='g';
                  if(go > 30)
                      count=count-1;
                      go=0;
                      sw="Off";
                  end
                  
               else
                   app.Lamp3.Color='r'; 
                   go=gate(app, app.s,0);
               end    
              pause(1);
              
              
           % display pannel 
              if(count<0)
                  count=0;
              end
              app.carCount.Value=int2str(count);
              vib =app.a.readVoltage('A0');
              if(vib<4.99)
                  app.a.writeDigitalPin('D5',1);
                  pause(1);
                  app.a.writeDigitalPin('D5',0);
              end
             
           end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
             app.stop=1;
             app.start=0;
             if (app.stop==1)
                app.Starter_text.Value= "Sorry Smart Gate System is Currently Closed";
                app.TextArea.Value="";
                app.Lamp1.Color='r';   % lamp b/w start and stop is red 
             else
                app.Starter_text.Value="Welcome to Smart Gate System";
                app.Lamp1.Color='g';   % lamp b/w start and stop is green 
             end
             clear app.a app.s all
             
             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.STARTButton = uibutton(app.Panel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.Panel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.Lamp1 = uilamp(app.Panel);
            app.Starter_text = uitextarea(app.Panel);
            app.observerultrasonicbuzzerPanel = uipanel(app.UIFigure);
            app.observerultrasonicbuzzerPanel.Title = 'observer  ( ultrasonic + buzzer )';
            app.TextArea = uitextarea(app.observerultrasonicbuzzerPanel);
            app.Lamp2 = uilamp(app.observerultrasonicbuzzerPanel);
            app.UIAxes = uiaxes(app.observerultrasonicbuzzerPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.buzzeralarmLampLabel = uilabel(app.observerultrasonicbuzzerPanel);
            app.buzzeralarmLampLabel.Text = 'buzzer alarm';
            app.buzzeralarmLamp = uilamp(app.observerultrasonicbuzzerPanel);
            app.gate_opening_panelservomotorPanel = uipanel(app.UIFigure);
            app.gate_opening_panelservomotorPanel.Title = 'gate_opening_panel ( servo motor ) ';
            app.opengateSwitchLabel = uilabel(app.gate_opening_panelservomotorPanel);
            app.opengateSwitchLabel.Text = 'open gate';
            app.opengateSwitch = uiswitch(app.gate_opening_panelservomotorPanel, 'toggle');
            app.Lamp3 = uilamp(app.gate_opening_panelservomotorPanel);
            app.gatecapacityKnobLabel = uilabel(app.gate_opening_panelservomotorPanel);
            app.gatecapacityKnobLabel.Text = 'gate capacity';
            app.gatecapacityKnob = uiknob(app.gate_opening_panelservomotorPanel, 'continuous');
            app.gateOutputGaugeLabel = uilabel(app.gate_opening_panelservomotorPanel);
            app.gateOutputGaugeLabel.Text = 'gate Output';
            app.gateOutputGauge = uigauge(app.gate_opening_panelservomotorPanel, 'semicircular');
            app.availabilityandsecuritypannelvibrationsensorPanel = uipanel(app.UIFigure);
            app.availabilityandsecuritypannelvibrationsensorPanel.Title = 'availability and security pannel ( vibration sensor)';
            app.carCount = uieditfield(app.availabilityandsecuritypannelvibrationsensorPanel, 'text');
            app.carCount.Value = ' 1';
            app.TotalcarswaitinginlaneLabel = uilabel(app.availabilityandsecuritypannelvibrationsensorPanel);
            app.TotalcarswaitinginlaneLabel.Text = {'Total cars waiting '; 'in lane :'};
            app.SMARTPARKINGSYSTEMLabel = uilabel(app.UIFigure);
            app.SMARTPARKINGSYSTEMLabel.Text = 'SMART PARKING SYSTEM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rough1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end